classdef UPVScanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        RealTimeTab              matlab.ui.container.Tab
        CentroDropDownLabel      matlab.ui.control.Label
        CentroDropDown           matlab.ui.control.DropDown
        EdificioDropDownLabel    matlab.ui.control.Label
        EdificioDropDown         matlab.ui.control.DropDown
        AulaDropDownLabel        matlab.ui.control.Label
        AulaDropDown             matlab.ui.control.DropDown
        StartButton              matlab.ui.control.Button
        CamaraDropDownLabel      matlab.ui.control.Label
        CamaraDropDown           matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        OCRTab                   matlab.ui.container.Tab
        ProcesarButton           matlab.ui.control.Button
        CentroDropDown_2Label    matlab.ui.control.Label
        CentroDropDown_2         matlab.ui.control.DropDown
        EdificioDropDown_2Label  matlab.ui.control.Label
        EdificioDropDown_2       matlab.ui.control.DropDown
        AulaDropDown_2Label      matlab.ui.control.Label
        AulaDropDown_2           matlab.ui.control.DropDown
        SubirButton              matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        webcamObject;
        imageObject;
        %fig=uifigure;%Venana de alertas
        infomsg=sprintf('Antes de pulsar el botón "Start" \n Por favor, Configure: \n CÁMARA, CENTRO, EDIFICIO y AULA');%mensaje de la ventana de alertas
        nomsg=sprintf('No esta usted matriculado/a en esta clase \n Por favor, Retirese');
        simsg=sprintf('Adelante \n \n Que tengas un buen día');
        conn=sqlite('C:\Users\jorge\Desktop\proyecto tdi\bd\test.db');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            uialert(app.UIFigure,app.infomsg,'INFO','Icon','info');
        end

        % Callback function
        function CentroDropDownOpening(app, event)
            if isempty(app.CentroDropDown.Value)
                app.EdificioDropDown.Items=[' ',' '];
            else
                aulasDeCentro=fetch(app.conn,"select * from Aula where centro='"+app.CentroDropDown.Value+"'");
                edificiosArray=cat(1,aulasDeCentro(:,2));
                listaDeEdificios=unique(edificiosArray(:,1));
                app.EdificioDropDown.Items=listaDeEdificios;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.CamaraDropDown.Value)
                app.webcamObject = webcam;
                
            else
                app.webcamObject=webcam(app.CamaraDropDown.Value);
                app.webcamObject.Resolution='1280x720';
            end
            app.imageObject = image(app.UIAxes);
            while true
                frame = flip(snapshot(app.webcamObject),2);
                Ish= insertShape(frame,'Rectangle', [200 100 880 500],'Color','red',"LineWidth",5);
                if ishandle(app.UIAxes)
                    imshow(Ish,'Parent', app.UIAxes);
                    croppedImage=detectorQR(frame);
                    [Nombre,DNI,Estado,Centro] = ExtractorDatosQR(croppedImage);
                    Respuesta=ComprobarBD(Nombre,app.CentroDropDown.Value,app.EdificioDropDown.Value,app.AulaDropDown.Value);
                    if Respuesta==200
                        uialert(app.UIFigure,app.simsg,'Aceptado','Icon','success');
                    elseif Respuesta==204 || Respuesta==401
                        uialert(app.UIFigure,app.nomsg+Nombre+", "+DNI+", "+Estado+", "+Centro,'Rechazado','Icon','warning');
                    end
                    
                else
                    break;
                end
            end
        end

        % Drop down opening function: CamaraDropDown
        function CamaraDropDownOpening(app, event)
            listaDeCamaras=webcamlist;
            app.CamaraDropDown.Items=listaDeCamaras;
        end

        % Value changed function: EdificioDropDown
        function EdificioDropDownValueChanged(app, event)
            if isempty(app.EdificioDropDown.Value)
                app.AulaDropDown.Items=[' ',' '];
            else
                aulasDeCentro=fetch(app.conn,"select * from Aula where edificio='"+app.EdificioDropDown.Value+"'");
                aulasArray=cat(1,aulasDeCentro(:,3));
                listaDeAulas=unique(aulasArray(:,1));
                app.AulaDropDown.Items=listaDeAulas;
            end
        end

        % Button pushed function: SubirButton
        function SubirButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.png';'*.tif'});
            frpath=strcat(path,file);
            frame=imread(frpath);
            app.Image.ImageSource=frame;
            app.ProcesarButton.Enable=true;
        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            frame=app.Image.ImageSource;
            NombreTarjeta=recoNombre(frame);
            RespuestaReco=ComprobarBD(NombreTarjeta,app.CentroDropDown.Value,app.EdificioDropDown.Value,app.AulaDropDown.Value);
            if     RespuestaReco==200
                uialert(app.UIFigure,app.simsg,'Aceptado','Icon','success');
            elseif RespuestaReco==204 || RespuestaReco==401
                uialert(app.UIFigure,app.nomsg,'Rechazado',"Icon","warning");
            end
            
        end

        % Value changed function: CentroDropDown
        function CentroDropDownValueChanged(app, event)
            value = app.CentroDropDown.Value;
            aulasDeCentro=fetch(app.conn,"select * from Aula where centro='"+value+"'");
            edificiosArray=cat(1,aulasDeCentro(:,2));
            listaDeEdificios=unique(edificiosArray(:,1));
            app.EdificioDropDown.Items=listaDeEdificios;
        end

        % Value changed function: EdificioDropDown_2
        function EdificioDropDown_2ValueChanged(app, event)
            edificio=app.EdificioDropDown_2.Value;
            
            aulasDeCentro=fetch(app.conn,"select * from Aula where edificio='"+edificio+"'");
            aulasArray=cat(1,aulasDeCentro(:,3));
            listaDeAulas=unique(aulasArray(:,1));
            app.AulaDropDown_2.Items=listaDeAulas;
        end

        % Value changed function: CentroDropDown_2
        function CentroDropDown_2ValueChanged(app, event)
           value = app.CentroDropDown_2.Value;
            aulasDeCentro=fetch(app.conn,"select * from Aula where centro='"+value+"'");
            edificiosArray=cat(1,aulasDeCentro(:,2));
            listaDeEdificios=unique(edificiosArray(:,1));
            app.EdificioDropDown_2.Items=listaDeEdificios;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'Real Time';
            app.CentroDropDownLabel = uilabel(app.RealTimeTab);
            app.CentroDropDownLabel.Text = 'Centro';
            app.CentroDropDown = uidropdown(app.RealTimeTab);
            app.CentroDropDown.Items = {'', 'EPSG', 'Vera', 'Alcoy'};
            app.CentroDropDown.ValueChangedFcn = createCallbackFcn(app, @CentroDropDownValueChanged, true);
            app.CentroDropDown.Value = '';
            app.EdificioDropDownLabel = uilabel(app.RealTimeTab);
            app.EdificioDropDownLabel.Text = 'Edificio';
            app.EdificioDropDown = uidropdown(app.RealTimeTab);
            app.EdificioDropDown.Items = {};
            app.EdificioDropDown.ValueChangedFcn = createCallbackFcn(app, @EdificioDropDownValueChanged, true);
            app.EdificioDropDown.Value = {};
            app.AulaDropDownLabel = uilabel(app.RealTimeTab);
            app.AulaDropDownLabel.Text = 'Aula';
            app.AulaDropDown = uidropdown(app.RealTimeTab);
            app.AulaDropDown.Items = {};
            app.AulaDropDown.Value = {};
            app.StartButton = uibutton(app.RealTimeTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CamaraDropDownLabel = uilabel(app.RealTimeTab);
            app.CamaraDropDownLabel.Text = 'Camara';
            app.CamaraDropDown = uidropdown(app.RealTimeTab);
            app.CamaraDropDown.Items = {};
            app.CamaraDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CamaraDropDownOpening, true);
            app.CamaraDropDown.Value = {};
            app.UIAxes = uiaxes(app.RealTimeTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.OCRTab = uitab(app.TabGroup);
            app.OCRTab.Title = 'OCR';
            app.ProcesarButton = uibutton(app.OCRTab, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Enable = 'off';
            app.ProcesarButton.Text = 'Procesar';
            app.CentroDropDown_2Label = uilabel(app.OCRTab);
            app.CentroDropDown_2Label.Text = 'Centro';
            app.CentroDropDown_2 = uidropdown(app.OCRTab);
            app.CentroDropDown_2.Items = {'', 'EPSG', 'Vera', 'Alcoy'};
            app.CentroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CentroDropDown_2ValueChanged, true);
            app.CentroDropDown_2.Value = '';
            app.EdificioDropDown_2Label = uilabel(app.OCRTab);
            app.EdificioDropDown_2Label.Text = 'Edificio';
            app.EdificioDropDown_2 = uidropdown(app.OCRTab);
            app.EdificioDropDown_2.Items = {};
            app.EdificioDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EdificioDropDown_2ValueChanged, true);
            app.EdificioDropDown_2.Value = {};
            app.AulaDropDown_2Label = uilabel(app.OCRTab);
            app.AulaDropDown_2Label.Text = 'Aula';
            app.AulaDropDown_2 = uidropdown(app.OCRTab);
            app.AulaDropDown_2.Items = {};
            app.AulaDropDown_2.Value = {};
            app.SubirButton = uibutton(app.OCRTab, 'push');
            app.SubirButton.ButtonPushedFcn = createCallbackFcn(app, @SubirButtonPushed, true);
            app.SubirButton.Text = 'Subir';
            app.Image = uiimage(app.OCRTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UPVScanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end