classdef hacerfotos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SwitchLabel  matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        webcamObject;
        imageObject; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.webcamObject = webcam;
            app.webcamObject.Resolution='1280x720';
            
            app.imageObject = image(app.UIAxes);
            while true
                frame = flip(snapshot(app.webcamObject),2);
                Ish= insertShape(frame,'Rectangle', [200 100 880 500],'Color','red',"LineWidth",5);                %info=detectorQR(frame);
                %if strcmp(info,'success')
                %end
                if strcmp(app.Switch.Value,"On")
                    i=randi([10 5000]);
                    file_name = sprintf('carnet%d.png',i);
                    imwrite(frame,file_name,'png');
                                        
                    app.Switch.Value="Off";
                    
                end
                if ishandle(app.UIAxes)
                    imshow(Ish,'Parent', app.UIAxes);
                    
                else
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hacerfotos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end